classdef layoutV4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        InputParametersTab           matlab.ui.container.Tab
        ControllerEditFieldLabel     matlab.ui.control.Label
        ControllerEditField          matlab.ui.control.EditField
        SubjectIDEditFieldLabel      matlab.ui.control.Label
        SubjectIDEditField           matlab.ui.control.EditField
        SpeedmsEditFieldLabel        matlab.ui.control.Label
        SpeedmsEditField             matlab.ui.control.EditField
        GradedegEditFieldLabel       matlab.ui.control.Label
        GradedegEditField            matlab.ui.control.EditField
        DatemmddyyyEditFieldLabel    matlab.ui.control.Label
        DatemmddyyyEditField         matlab.ui.control.EditField
        FileInfoLabel                matlab.ui.control.Label
        InitializationLabel          matlab.ui.control.Label
        SigmaEditFieldLabel          matlab.ui.control.Label
        SigmaEditField               matlab.ui.control.NumericEditField
        GenerationsEditFieldLabel    matlab.ui.control.Label
        GenerationsEditField         matlab.ui.control.NumericEditField
        TrialEditFieldLabel          matlab.ui.control.Label
        TrialEditField               matlab.ui.control.EditField
        ParameterStartingGuessLabel  matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        CalibrateCameraButton        matlab.ui.control.Button
        CameraCalibrationPanel       matlab.ui.container.Panel
        CloseButton                  matlab.ui.control.Button
        TestModeEditFieldLabel       matlab.ui.control.Label
        TestModeEditField            matlab.ui.control.NumericEditField
        TestMethodEditFieldLabel     matlab.ui.control.Label
        TestMethodEditField          matlab.ui.control.NumericEditField
        MinsEditFieldLabel           matlab.ui.control.Label
        MinsEditField                matlab.ui.control.NumericEditField
        BoxWidthEditFieldLabel       matlab.ui.control.Label
        BoxWidthEditField            matlab.ui.control.NumericEditField
        BoxHeightEditFieldLabel      matlab.ui.control.Label
        BoxHeightEditField           matlab.ui.control.NumericEditField
        TrialEditField_2Label        matlab.ui.control.Label
        TrialEditField_2             matlab.ui.control.NumericEditField
        CalibrateButton              matlab.ui.control.Button
        NumberofParametersEditField  matlab.ui.control.NumericEditField
        KnobLabel                    matlab.ui.control.Label
        Knob                         matlab.ui.control.DiscreteKnob
        SigmaEditFieldLabel_2        matlab.ui.control.Label
        PlotsTab                     matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        OutputDataTab                matlab.ui.container.Tab
        UITable3                     matlab.ui.control.Table
        Label                        matlab.ui.control.Label
        AllChildrenLabel             matlab.ui.control.Label
        UITable4                     matlab.ui.control.Table
        CurrentGenerationNumberEditFieldLabel  matlab.ui.control.Label
        CurrentGenerationNumberEditField  matlab.ui.control.EditField
        DataLabel                    matlab.ui.control.Label
        nTextAreaLabel               matlab.ui.control.Label
        nTextArea                    matlab.ui.control.TextArea
    end

    
    methods (Access = private)
        
        function HiLO_setup(app)
            %% Initialize Parameters and Update File Information
            clearvars fileInfo
            fileInfo.controller = app.ControllerEditField.Value;
            fileInfo.subjectID = app.SubjectIDEditField.Value;
            fileInfo.speed = app.SpeedmsEditField.Value;
            fileInfo.grade = app.GradedegEditField.Value;
            
            formatOut = 'mmddyyyy';
            fileInfo.date = datestr(now,formatOut);
            
            N=app.NumberofParametersEditField.Value; % number of parameters
            sigma = app.SigmaEditField.Value;
            generations = app.GenerationsEditField.Value;
            trial = app.TrialEditField.Value;
            
            % Parameter inputs
            paramNames(1:N) = app.UITable.Data(:,1);
            paramMeans = cell2mat(app.UITable.Data(:,2));
            paramMins = cell2mat(app.UITable.Data(:,3));
            paramMaxes = cell2mat(app.UITable.Data(:,4));
            
            %% Run CMAES
            dateStart = app.DatemmddyyyEditField.Value;
            if ~ischar(dateStart)
                dateStart = num2str(dateStart);
            end
            if strcmp(fileInfo.date,dateStart)
                % Create new file when there is new filename
                counteval = 0;  % Number of evalution finished
                filename = sprintf('%s_%s_%s_%s_%s_test%s.mat',fileInfo.controller,...
                    fileInfo.subjectID,fileInfo.speed,fileInfo.grade,fileInfo.date,trial);
                save(filename);
            else
                % Use old filename
                fileInfo.date = dateStart;
                filename = sprintf('%s_%s_%s_%s_%s_test%s.mat',fileInfo.controller,...
                    fileInfo.subjectID,fileInfo.speed,fileInfo.grade,fileInfo.date,trial);
                sigma = load(filename,'sigma');
            
            end
                %mess with UITable for outputs after we get the script finished
               
                [xmin,CMAES_struct]=CMAES_GUI_V2(filename,sigma,generations,N,paramNames,paramMeans,paramMins,paramMaxes);                
                app.UITable4.Data = [[CMAES_struct.gen_idx].' [CMAES_struct.min_metrate].',[CMAES_struct.sigma].',[CMAES_struct.x_mean].'];
                 for j = 1:N
                    app.UITable4.ColumnName(end+1) = paramNames(j);
                    j=j+1;
                 end
              y = size(CMAES_struct(1).x);
              y = y(2);
              gencol = repmat([CMAES_struct.gen_idx], generations,1);
              matrix = [];
              for z = 1:generations
                  app.UITable3.Data(y*(z-1)+1:y*z,1:N) = CMAES_struct(z).x.';
                  matrix = [matrix; gencol(:,z)];
              end
              for j = 1:N
                    app.UITable3.ColumnName(j) = paramNames(j);
                    j=j+1;
              end
             
                          
                app.UIAxes.XLim = [0 generations];
                app.UIAxes.YLim = [paramMins(1) paramMaxes(1)];
        end
             
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: CalibrateCameraButton
        function CalibrateCameraButtonPushed(app, event)
            app.CameraCalibrationPanel.Visible = 'on';
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.CameraCalibrationPanel.Visible = 'off';
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            %getmetabolics_V7(app.TestModeEditField.Value, app.TestMethodEditField.Value, app.MinsEditField.Value, app.BoxWidthEditField.Value, app.BoxHeightEditField.Value, app.TrialEditField_2.Value);
        end

        % Value changed function: NumberofParametersEditField
        function NumberofParametersEditFieldValueChanged(app, event)
            vec = zeros(app.NumberofParametersEditField.Value,4);
            vec = num2cell(vec);
            vec(1:end, 1) = {'Parameter'} ;
            app.UITable.Data = vec;  %just makes the table the correct size based on what's supposed to go here
            app.UITable.Enable ='on';
            app.nTextArea.Value = num2str(app.NumberofParametersEditField.Value);
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            if strcmp(value,'Run')
               HiLO_setup(app);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputParametersTab = uitab(app.TabGroup);
            app.InputParametersTab.Title = 'Input Parameters';
            app.ControllerEditFieldLabel = uilabel(app.InputParametersTab);
            app.ControllerEditFieldLabel.Text = 'Controller';
            app.ControllerEditField = uieditfield(app.InputParametersTab, 'text');
            app.SubjectIDEditFieldLabel = uilabel(app.InputParametersTab);
            app.SubjectIDEditFieldLabel.Text = 'Subject ID';
            app.SubjectIDEditField = uieditfield(app.InputParametersTab, 'text');
            app.SpeedmsEditFieldLabel = uilabel(app.InputParametersTab);
            app.SpeedmsEditFieldLabel.Text = 'Speed [m/s]';
            app.SpeedmsEditField = uieditfield(app.InputParametersTab, 'text');
            app.GradedegEditFieldLabel = uilabel(app.InputParametersTab);
            app.GradedegEditFieldLabel.Text = 'Grade [deg] ';
            app.GradedegEditField = uieditfield(app.InputParametersTab, 'text');
            app.DatemmddyyyEditFieldLabel = uilabel(app.InputParametersTab);
            app.DatemmddyyyEditFieldLabel.Text = 'Date [mmddyyy]';
            app.DatemmddyyyEditField = uieditfield(app.InputParametersTab, 'text');
            app.FileInfoLabel = uilabel(app.InputParametersTab);
            app.FileInfoLabel.Text = 'File Info';
            app.InitializationLabel = uilabel(app.InputParametersTab);
            app.InitializationLabel.Text = 'Initialization';
            app.SigmaEditFieldLabel = uilabel(app.InputParametersTab);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.InputParametersTab, 'numeric');
            app.GenerationsEditFieldLabel = uilabel(app.InputParametersTab);
            app.GenerationsEditFieldLabel.Text = 'Generations';
            app.GenerationsEditField = uieditfield(app.InputParametersTab, 'numeric');
            app.TrialEditFieldLabel = uilabel(app.InputParametersTab);
            app.TrialEditFieldLabel.Text = 'Trial';
            app.TrialEditField = uieditfield(app.InputParametersTab, 'text');
            app.ParameterStartingGuessLabel = uilabel(app.InputParametersTab);
            app.ParameterStartingGuessLabel.Enable = 'off';
            app.ParameterStartingGuessLabel.Text = 'Parameter Starting Guess';
            app.UITable = uitable(app.InputParametersTab);
            app.UITable.ColumnName = {'Name'; 'Mean'; 'Min'; 'Max'};
            app.UITable.RowName = {''};
            app.UITable.ColumnEditable = true;
            app.UITable.Enable = 'off';
            app.CalibrateCameraButton = uibutton(app.InputParametersTab, 'push');
            app.CalibrateCameraButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateCameraButtonPushed, true);
            app.CalibrateCameraButton.Text = 'Calibrate Camera';
            app.CameraCalibrationPanel = uipanel(app.InputParametersTab);
            app.CameraCalibrationPanel.Title = 'Camera Calibration';
            app.CameraCalibrationPanel.Visible = 'off';
            app.CloseButton = uibutton(app.CameraCalibrationPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TestModeEditFieldLabel = uilabel(app.CameraCalibrationPanel);
            app.TestModeEditFieldLabel.Text = 'Test Mode';
            app.TestModeEditField = uieditfield(app.CameraCalibrationPanel, 'numeric');
            app.TestModeEditField.Value = 2;
            app.TestMethodEditFieldLabel = uilabel(app.CameraCalibrationPanel);
            app.TestMethodEditFieldLabel.Text = 'Test Method';
            app.TestMethodEditField = uieditfield(app.CameraCalibrationPanel, 'numeric');
            app.TestMethodEditField.Value = 1;
            app.MinsEditFieldLabel = uilabel(app.CameraCalibrationPanel);
            app.MinsEditFieldLabel.Text = 'Mins';
            app.MinsEditField = uieditfield(app.CameraCalibrationPanel, 'numeric');
            app.MinsEditField.Value = 0.25;
            app.BoxWidthEditFieldLabel = uilabel(app.CameraCalibrationPanel);
            app.BoxWidthEditFieldLabel.Text = 'Box Width';
            app.BoxWidthEditField = uieditfield(app.CameraCalibrationPanel, 'numeric');
            app.BoxWidthEditField.Value = 300;
            app.BoxHeightEditFieldLabel = uilabel(app.CameraCalibrationPanel);
            app.BoxHeightEditFieldLabel.Text = 'Box Height';
            app.BoxHeightEditField = uieditfield(app.CameraCalibrationPanel, 'numeric');
            app.BoxHeightEditField.Value = 125;
            app.TrialEditField_2Label = uilabel(app.CameraCalibrationPanel);
            app.TrialEditField_2Label.Text = 'Trial';
            app.TrialEditField_2 = uieditfield(app.CameraCalibrationPanel, 'numeric');
            app.TrialEditField_2.Value = 1;
            app.CalibrateButton = uibutton(app.CameraCalibrationPanel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.NumberofParametersEditField = uieditfield(app.InputParametersTab, 'numeric');
            app.NumberofParametersEditField.Limits = [0 Inf];
            app.NumberofParametersEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofParametersEditFieldValueChanged, true);
            app.KnobLabel = uilabel(app.InputParametersTab);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.InputParametersTab, 'discrete');
            app.Knob.Items = {'Stop', 'Pause', 'Run'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Value = 'Stop';
            app.SigmaEditFieldLabel_2 = uilabel(app.InputParametersTab);
            app.SigmaEditFieldLabel_2.Text = 'Number of Parameters';
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.UIAxes = uiaxes(app.PlotsTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.OutputDataTab = uitab(app.TabGroup);
            app.OutputDataTab.Title = 'Output Data';
            app.UITable3 = uitable(app.OutputDataTab);
            app.UITable3.ColumnName = {''; ''};
            app.UITable3.RowName = {};
            app.Label = uilabel(app.OutputDataTab);
            app.Label.Text = '';
            app.AllChildrenLabel = uilabel(app.OutputDataTab);
            app.AllChildrenLabel.Text = 'All Children';
            app.UITable4 = uitable(app.OutputDataTab);
            app.UITable4.ColumnName = {'Generation'; 'Min Metabolic Rate'; 'Sigma'};
            app.UITable4.RowName = {};
            app.CurrentGenerationNumberEditFieldLabel = uilabel(app.OutputDataTab);
            app.CurrentGenerationNumberEditFieldLabel.Text = 'Current Generation Number';
            app.CurrentGenerationNumberEditField = uieditfield(app.OutputDataTab, 'text');
            app.CurrentGenerationNumberEditField.Editable = 'off';
            app.DataLabel = uilabel(app.OutputDataTab);
            app.DataLabel.Text = 'Data';
            app.nTextAreaLabel = uilabel(app.OutputDataTab);
            app.nTextAreaLabel.Text = 'n';
            app.nTextArea = uitextarea(app.OutputDataTab);
            app.nTextArea.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = layoutV4

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end